classdef GuiLogger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoggerUIFigure         matlab.ui.Figure
        Axes                   matlab.ui.control.UIAxes
        FileEditFieldLabel     matlab.ui.control.Label
        FileEditField          matlab.ui.control.EditField
        LegendArea             matlab.ui.control.TextArea
        logscaleYCheckBox      matlab.ui.control.CheckBox
        TimelabelsPanel        matlab.ui.container.Panel
        AddButton              matlab.ui.control.Button
        SelectedDropDownLabel  matlab.ui.control.Label
        SelectedDropDown       matlab.ui.control.DropDown
        DeleteButton           matlab.ui.control.Button
        ModifyButton           matlab.ui.control.Button
        DisplayCheckBox        matlab.ui.control.CheckBox
        MeasurementPanel       matlab.ui.container.Panel
        MeasButton             matlab.ui.control.StateButton
        IntervalsLabel         matlab.ui.control.Label
        MeasperiodsEditField   matlab.ui.control.NumericEditField
        SavecontCheckBox       matlab.ui.control.CheckBox
        SaverecordButton       matlab.ui.control.Button
        ClearrecordButton      matlab.ui.control.Button
        PlotlimitsPanel        matlab.ui.container.Panel
        yminEditFieldLabel     matlab.ui.control.Label
        yminEditField          matlab.ui.control.NumericEditField
        ymaxEditFieldLabel     matlab.ui.control.Label
        ymaxEditField          matlab.ui.control.NumericEditField
        AutoYrangeCheckBox     matlab.ui.control.CheckBox
        DisplaytracesPanel     matlab.ui.container.Panel
        CheckBox               matlab.ui.control.CheckBox
        CheckBox_2             matlab.ui.control.CheckBox
        CheckBox_3             matlab.ui.control.CheckBox
        CheckBox_4             matlab.ui.control.CheckBox
        xminEditField_2Label   matlab.ui.control.Label
        xminEditField          matlab.ui.control.EditField
        xmaxEditField_2Label   matlab.ui.control.Label
        xmaxEditField          matlab.ui.control.EditField
        modeLabel              matlab.ui.control.Label
        XLimModeDropDown       matlab.ui.control.DropDown
    end


    properties (Access = public)
        name = 'Logger' % Name of the logger window
        linked_elem_list = {}; % List of elements updated by updateGui
        Lg % Logger object
        
        % Format for displaying last reading (label: value)
        disp_fmt = '%15s: %.2e'
    end

    properties (Access = private)
        Llh % Listener to the logger NewData event 
    end
    
    methods (Access = public)
        % creating a generic callback
        function cb = createGenericCallback(app)
            cb = createCallbackFcn(app,...
                @(src, event)genericValueChanged(src, event), true);
        end
    end

    methods (Access = private)
    
        function createLinks(app)
            app.FileEditField.Tag = 'Lg.Record.file_name';
            app.linked_elem_list = [app.linked_elem_list, app.FileEditField];
            
            app.SavecontCheckBox.Tag = 'Lg.save_cont';
            app.linked_elem_list = [app.linked_elem_list, app.SavecontCheckBox];
            
            app.MeasperiodsEditField.Tag = 'Lg.MeasTimer.Period';
            app.linked_elem_list = [app.linked_elem_list, app.MeasperiodsEditField];
            
            app.MeasButton.Tag = 'Lg.MeasTimer.Running';
            addprop(app.MeasButton,'OutputProcessingFcn');
            app.MeasButton.OutputProcessingFcn = @(str)setMeasButton(app,str);
            app.linked_elem_list = [app.linked_elem_list, app.MeasButton];
            
            app.xminEditField.Tag = 'Axes.XLim';
            addprop(app.xminEditField,'OutputProcessingFcn');
            app.xminEditField.OutputProcessingFcn = @(x)char(x(1));
            app.linked_elem_list = [app.linked_elem_list, app.xminEditField];
            
            app.xmaxEditField.Tag = 'Axes.XLim';
            addprop(app.xmaxEditField,'OutputProcessingFcn');
            app.xmaxEditField.OutputProcessingFcn = @(x)char(x(2));
            app.linked_elem_list = [app.linked_elem_list, app.xmaxEditField];
            
%             app.xmaxEditField.Tag = 'Axes.XLim';
%             addprop(app.xmaxEditField,'OutputProcessingFcn');
%             app.xmaxEditField.OutputProcessingFcn = @(x)x(2);
%             app.linked_elem_list = [app.linked_elem_list, app.xmaxEditField];
            
            linkGuiElement(app, app.XLimModeDropDown, 'Axes.XLimMode');
        end
        
        function updateDataDisp(app)
            % plot using MyLog method
            plot(app.Lg.Record, app.Axes);
            app.LegendArea.Value=printLastReading(app.Lg.Record);
        end
     
        function updateGui(app)
            updateLinkedGuiElements(app);
            updateDataDisp(app);
        end
    
        function val = setMeasButton(app, str)
            if strcmpi(str,'on') 
                val = 1;
                app.MeasButton.Text = 'Stop';
            else 
                val = 0;
                app.MeasButton.Text = 'Start';
            end
        end
    
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Logger, varargin)
            p=inputParser();
            p.KeepUnmatched = true;
            addParameter(p,'name',[]);
            parse(p, varargin{:});
            
            app.Lg = Logger;
            
            if ~ismember('name',p.UsingDefaults)
                app.name = p.Results.name;
            end
            app.LoggerUIFigure.Name = app.name;
            
            app.Llh = addlistener(app.Lg, 'NewData', @(~,~)updateGui(app));
            
            createLinks(app);
            updateGui(app);
        end

        % Value changed function: FileEditField
        function FileEditFieldValueChanged(app, event)
            value = app.FileEditField.Value;
            app.Lg.save_file = value;
        end

        % Value changed function: MeasButton
        function MeasButtonValueChanged(app, event)
            value = app.MeasButton.Value;
            setMeasButton(app, value);
            if value
                start(app.Lg);
                app.MeasperiodsEditField.Editable='off';
            else
                stop(app.Lg);
                app.MeasperiodsEditField.Editable='on';
            end
            updateGui(app);
        end

        % Value changed function: SavecontCheckBox
        function SavecontCheckBoxValueChanged(app, event)
            value = app.SavecontCheckBox.Value;
            app.Lg.save_cont = value;
        end

        % Button pushed function: ClearrecordButton
        function ClearrecordButtonPushed(app, event)
            clearLog(app.Lg);
            updateGui(app);
        end

        % Button pushed function: SaverecordButton
        function SaverecordButtonPushed(app, event)
            saveLog(app.Lg);
        end

        % Value changed function: MeasperiodsEditField
        function MeasperiodsEditFieldValueChanged(app, event)
            value = app.MeasperiodsEditField.Value;
            app.Lg.MeasTimer.Period = value;
        end

        % Close request function: LoggerUIFigure
        function LoggerUIFigureCloseRequest(app, event)
            delete(app.Lg);
            delete(app);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            addTimeLabel(app.Lg.Record);
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoggerUIFigure = uifigure;
            app.LoggerUIFigure.Name = 'Logger';
            app.LoggerUIFigure.CloseRequestFcn = createCallbackFcn(app, @LoggerUIFigureCloseRequest, true);
            app.Axes = uiaxes(app.LoggerUIFigure);
            xlabel(app.Axes, 'Time')
            app.FileEditFieldLabel = uilabel(app.LoggerUIFigure);
            app.FileEditFieldLabel.Text = ' File';
            app.FileEditField = uieditfield(app.LoggerUIFigure, 'text');
            app.FileEditField.ValueChangedFcn = createCallbackFcn(app, @FileEditFieldValueChanged, true);
            app.FileEditField.Value = 'M:\Measurement Campaigns\2018-07-13 Pressure Log';
            app.LegendArea = uitextarea(app.LoggerUIFigure);
            app.logscaleYCheckBox = uicheckbox(app.LoggerUIFigure);
            app.logscaleYCheckBox.Text = 'log scale Y';
            app.logscaleYCheckBox.Value = true;
            app.TimelabelsPanel = uipanel(app.LoggerUIFigure);
            app.TimelabelsPanel.Title = 'Time labels';
            app.AddButton = uibutton(app.TimelabelsPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.SelectedDropDownLabel = uilabel(app.TimelabelsPanel);
            app.SelectedDropDownLabel.Text = 'Selected';
            app.SelectedDropDown = uidropdown(app.TimelabelsPanel);
            app.SelectedDropDown.Items = {};
            app.SelectedDropDown.Value = {};
            app.DeleteButton = uibutton(app.TimelabelsPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.ModifyButton = uibutton(app.TimelabelsPanel, 'push');
            app.ModifyButton.Text = 'Modify';
            app.DisplayCheckBox = uicheckbox(app.TimelabelsPanel);
            app.DisplayCheckBox.Text = 'Display';
            app.MeasurementPanel = uipanel(app.LoggerUIFigure);
            app.MeasurementPanel.Title = 'Measurement';
            app.MeasButton = uibutton(app.MeasurementPanel, 'state');
            app.MeasButton.ValueChangedFcn = createCallbackFcn(app, @MeasButtonValueChanged, true);
            app.MeasButton.Text = 'Start';
            app.IntervalsLabel = uilabel(app.MeasurementPanel);
            app.IntervalsLabel.Text = 'Interval (s)';
            app.MeasperiodsEditField = uieditfield(app.MeasurementPanel, 'numeric');
            app.MeasperiodsEditField.ValueChangedFcn = createCallbackFcn(app, @MeasperiodsEditFieldValueChanged, true);
            app.SavecontCheckBox = uicheckbox(app.MeasurementPanel);
            app.SavecontCheckBox.ValueChangedFcn = createCallbackFcn(app, @SavecontCheckBoxValueChanged, true);
            app.SavecontCheckBox.Text = 'Save continuously';
            app.SaverecordButton = uibutton(app.MeasurementPanel, 'push');
            app.SaverecordButton.ButtonPushedFcn = createCallbackFcn(app, @SaverecordButtonPushed, true);
            app.SaverecordButton.Text = 'Save record';
            app.ClearrecordButton = uibutton(app.MeasurementPanel, 'push');
            app.ClearrecordButton.ButtonPushedFcn = createCallbackFcn(app, @ClearrecordButtonPushed, true);
            app.ClearrecordButton.Text = 'Clear record';
            app.PlotlimitsPanel = uipanel(app.LoggerUIFigure);
            app.PlotlimitsPanel.Title = 'Plot limits';
            app.yminEditFieldLabel = uilabel(app.PlotlimitsPanel);
            app.yminEditFieldLabel.Text = 'y min';
            app.yminEditField = uieditfield(app.PlotlimitsPanel, 'numeric');
            app.ymaxEditFieldLabel = uilabel(app.PlotlimitsPanel);
            app.ymaxEditFieldLabel.Text = 'y max';
            app.ymaxEditField = uieditfield(app.PlotlimitsPanel, 'numeric');
            app.AutoYrangeCheckBox = uicheckbox(app.PlotlimitsPanel);
            app.AutoYrangeCheckBox.Text = 'Auto?';
            app.DisplaytracesPanel = uipanel(app.LoggerUIFigure);
            app.DisplaytracesPanel.Title = 'Display traces';
            app.CheckBox = uicheckbox(app.DisplaytracesPanel);
            app.CheckBox.Text = '1';
            app.CheckBox_2 = uicheckbox(app.DisplaytracesPanel);
            app.CheckBox_2.Text = '2';
            app.CheckBox_3 = uicheckbox(app.DisplaytracesPanel);
            app.CheckBox_3.Text = '3';
            app.CheckBox_4 = uicheckbox(app.DisplaytracesPanel);
            app.CheckBox_4.Text = '4';
            app.xminEditField_2Label = uilabel(app.LoggerUIFigure);
            app.xminEditField_2Label.Text = 'x min';
            app.xminEditField = uieditfield(app.LoggerUIFigure, 'text');
            app.xminEditField.Value = '23-Dec-2018 15:59:08';
            app.xmaxEditField_2Label = uilabel(app.LoggerUIFigure);
            app.xmaxEditField_2Label.Text = 'x max';
            app.xmaxEditField = uieditfield(app.LoggerUIFigure, 'text');
            app.xmaxEditField.Value = '23-Dec-2018 15:59:08';
            app.modeLabel = uilabel(app.LoggerUIFigure);
            app.modeLabel.Text = 'mode';
            app.XLimModeDropDown = uidropdown(app.LoggerUIFigure);
            app.XLimModeDropDown.Items = {'auto', 'manual'};
            app.XLimModeDropDown.Value = 'auto';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GuiLogger(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoggerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoggerUIFigure)
        end
    end
end