classdef GuiZiRingdown < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ZiRingdownUIFigure              matlab.ui.Figure
        EnableaquisitionButton          matlab.ui.control.StateButton
        RecordingLampLabel              matlab.ui.control.Label
        RecordingLamp                   matlab.ui.control.Lamp
        CurrentoscillatorEditFieldLabel  matlab.ui.control.Label
        CurrentoscillatorEditField      matlab.ui.control.NumericEditField
        RecordtimesEditFieldLabel       matlab.ui.control.Label
        RecordtimesEditField            matlab.ui.control.NumericEditField
        TimeelapsedsEditFieldLabel      matlab.ui.control.Label
        TimeelapsedsEditField           matlab.ui.control.NumericEditField
        AdaptivedemodfrequencyCheckBox  matlab.ui.control.CheckBox
        ThresholdVEditFieldLabel        matlab.ui.control.Label
        ThresholdVEditField             matlab.ui.control.NumericEditField
        UIAxes2                         matlab.ui.control.UIAxes
        NaverageEditFieldLabel          matlab.ui.control.Label
        NaverageEditField               matlab.ui.control.NumericEditField
        DriveLampLabel                  matlab.ui.control.Label
        DriveLamp                       matlab.ui.control.Lamp
        AutosaveeachtraceCheckBox       matlab.ui.control.CheckBox
        CompletedaveragesEditFieldLabel  matlab.ui.control.Label
        CompletedaveragesEditField      matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        % Necessary variables for instrument Gui
        Instr % Instrument object
        linked_elem_list={};
        name = ''  % App name to clear on exit
        Listeners
    end

    methods (Access = public)
        
        % Automatic callback assignment function
        function cb = createGenericCallback(app)
            cb = createCallbackFcn(app,...
                @(src, event)genericValueChanged(src, event), true);
        end
    
    end
    
    methods (Access = private)
        
        % Lineked control elements are updated by calling updateGui(app)
        function createlLinks(app)
            linkGuiElement(app, app.RecordingLamp, '.Instr.recording');
            linkGuiElement(app, app.EnableaquisitionButton, '.Instr.enable_acq');
            linkGuiElement(app, app.ThresholdVEditField, '.Instr.trig_threshold');
            linkGuiElement(app, app.RecordtimesEditField, '.Instr.record_time');
            linkGuiElement(app, app.CurrentoscillatorEditField, '.Instr.current_osc');
            linkGuiElement(app, app.TimeelapsedsEditField, '.Instr.elapsed_t');
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Instrument, varargin)
            app.Instr = Instrument;
            createLinks(app);
        end

        % Close request function: ZiRingdownUIFigure
        function ZiRingdownUIFigureCloseRequest(app, event)
            % Delete instrument object, clear global variable and delete app
            deleteInstrGui(app);
        end

        % Value changed function: EnableaquisitionButton
        function EnableaquisitionButtonValueChanged(app, event)
            value = app.EnableaquisitionButton.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ZiRingdownUIFigure = uifigure;
            app.ZiRingdownUIFigure.Name = 'Template';
            app.ZiRingdownUIFigure.CloseRequestFcn = createCallbackFcn(app, @ZiRingdownUIFigureCloseRequest, true);
            app.EnableaquisitionButton = uibutton(app.ZiRingdownUIFigure, 'state');
            app.EnableaquisitionButton.ValueChangedFcn = createCallbackFcn(app, @EnableaquisitionButtonValueChanged, true);
            app.EnableaquisitionButton.Text = 'Enable aquisition';
            app.RecordingLampLabel = uilabel(app.ZiRingdownUIFigure);
            app.RecordingLampLabel.Text = 'Recording';
            app.RecordingLamp = uilamp(app.ZiRingdownUIFigure);
            app.CurrentoscillatorEditFieldLabel = uilabel(app.ZiRingdownUIFigure);
            app.CurrentoscillatorEditFieldLabel.Text = 'Current oscillator';
            app.CurrentoscillatorEditField = uieditfield(app.ZiRingdownUIFigure, 'numeric');
            app.CurrentoscillatorEditField.ValueDisplayFormat = '%.0f';
            app.CurrentoscillatorEditField.Editable = 'off';
            app.RecordtimesEditFieldLabel = uilabel(app.ZiRingdownUIFigure);
            app.RecordtimesEditFieldLabel.Text = 'Record time (s)';
            app.RecordtimesEditField = uieditfield(app.ZiRingdownUIFigure, 'numeric');
            app.RecordtimesEditField.Value = 150;
            app.TimeelapsedsEditFieldLabel = uilabel(app.ZiRingdownUIFigure);
            app.TimeelapsedsEditFieldLabel.Text = 'Time elapsed (s)';
            app.TimeelapsedsEditField = uieditfield(app.ZiRingdownUIFigure, 'numeric');
            app.TimeelapsedsEditField.Editable = 'off';
            app.AdaptivedemodfrequencyCheckBox = uicheckbox(app.ZiRingdownUIFigure);
            app.AdaptivedemodfrequencyCheckBox.Enable = 'off';
            app.AdaptivedemodfrequencyCheckBox.Text = 'Adaptive demod frequency';
            app.ThresholdVEditFieldLabel = uilabel(app.ZiRingdownUIFigure);
            app.ThresholdVEditFieldLabel.Text = 'Threshold (V)';
            app.ThresholdVEditField = uieditfield(app.ZiRingdownUIFigure, 'numeric');
            app.UIAxes2 = uiaxes(app.ZiRingdownUIFigure);
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'r')
            app.NaverageEditFieldLabel = uilabel(app.ZiRingdownUIFigure);
            app.NaverageEditFieldLabel.Enable = 'off';
            app.NaverageEditFieldLabel.Text = 'N average';
            app.NaverageEditField = uieditfield(app.ZiRingdownUIFigure, 'numeric');
            app.NaverageEditField.Enable = 'off';
            app.NaverageEditField.Value = 1;
            app.DriveLampLabel = uilabel(app.ZiRingdownUIFigure);
            app.DriveLampLabel.Enable = 'off';
            app.DriveLampLabel.Text = 'Drive';
            app.DriveLamp = uilamp(app.ZiRingdownUIFigure);
            app.DriveLamp.Enable = 'off';
            app.AutosaveeachtraceCheckBox = uicheckbox(app.ZiRingdownUIFigure);
            app.AutosaveeachtraceCheckBox.Enable = 'off';
            app.AutosaveeachtraceCheckBox.Text = 'Auto save each trace';
            app.CompletedaveragesEditFieldLabel = uilabel(app.ZiRingdownUIFigure);
            app.CompletedaveragesEditFieldLabel.Enable = 'off';
            app.CompletedaveragesEditFieldLabel.Text = 'Completed averages';
            app.CompletedaveragesEditField = uieditfield(app.ZiRingdownUIFigure, 'numeric');
            app.CompletedaveragesEditField.ValueDisplayFormat = '%.0f';
            app.CompletedaveragesEditField.Enable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GuiZiRingdown(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ZiRingdownUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ZiRingdownUIFigure)
        end
    end
end