classdef InstrumentManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ManagethelistofinstrumentsUIFigure  matlab.ui.Figure
        InstrTable                   matlab.ui.control.Table
        AddinstrumentButton          matlab.ui.control.Button
        DeleteinstrumentButton       matlab.ui.control.Button
        SavelistButton               matlab.ui.control.Button
        ReloadlistButton             matlab.ui.control.Button
        ContinueButton               matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        InstrumentlistTab            matlab.ui.container.Tab
        StatusLabel                  matlab.ui.control.Label
        InstrumentListlocationLabel  matlab.ui.control.Label
        BaseDirEditField             matlab.ui.control.EditField
        RunfilesTab                  matlab.ui.container.Tab
        GeneraterunfileButton        matlab.ui.control.Button
        ShowinDAQCheckBox            matlab.ui.control.CheckBox
        MenutitleEditFieldLabel      matlab.ui.control.Label
        MenutitleEditField           matlab.ui.control.EditField
        OptionsLabel                 matlab.ui.control.Label
    end


    properties (Access = public)   
        row_sel = 1; % selected row
        Ret % variable with the value passed by reference to return the choosen device
        
        NORMAL_COLOR = [0.47, 0.67, 0.19];
        ERROR_COLOR = [1, 0, 0];
        WARNING_COLOR = [0.93, 0.69, 0.13];
        
        EDIT_BG_COLOR = [1,1,0.9]; % yellow if changes unsaved
        STD_BG_COLOR = [1,1,1]; % white
    end

    methods (Access = private)
    
        function updateTable(app)
            try
                InstrumentList = getLocalSettings('InstrumentList');
                instr_names = fieldnames(InstrumentList);
                app.InstrTable.Data = cell(length(instr_names),5);
                for i=1:length(instr_names)
                    name = instr_names{i};
                    app.InstrTable.Data(i,1:end)={name,...
                        InstrumentList.(name).interface,...
                        InstrumentList.(name).address,...
                        InstrumentList.(name).control_class,...
                        InstrumentList.(name).default_gui};
                end
                app.StatusLabel.Text = 'Instruments file loaded';
                app.StatusLabel.FontColor = app.NORMAL_COLOR;
                app.InstrTable.BackgroundColor = app.STD_BG_COLOR;
            catch
                app.file_name = app.BaseDirEditField.Value;
                app.StatusLabel.Text = ...
                    'InstrumentList is not found or is not of expected format';
                app.StatusLabel.FontColor = app.ERROR_COLOR;
            end
        end
        
        function setTableStatus(app, status)
            switch status
                case 'edits_unsaved'
                    app.InstrTable.BackgroundColor = app.EDIT_BG_COLOR;
                    app.StatusLabel.Text = 'List of instruments contains unsaved changes';
                    app.StatusLabel.FontColor = app.WARNING_COLOR;
                case 'edits_saved'
                    app.InstrTable.BackgroundColor = app.STD_BG_COLOR;
                    app.StatusLabel.Text = 'Changes saved';
                    app.StatusLabel.FontColor = app.NORMAL_COLOR;
                otherwise
                    error('Unknown table status')
            end
        end
        
        function InstrumentList = instrListFromTable(app)
            InstrumentList = struct();
            table_size = size(app.InstrTable.Data);
            % table_size(1) is the number of rows
            for i=1:table_size(1)
                tmp_name = app.InstrTable.Data{i,1};
                InstrumentList.(tmp_name) = struct();
                InstrumentList.(tmp_name).name = tmp_name;
                InstrumentList.(tmp_name).interface = app.InstrTable.Data{i,2};
                InstrumentList.(tmp_name).address = app.InstrTable.Data{i,3};
                InstrumentList.(tmp_name).control_class = app.InstrTable.Data{i,4};
                InstrumentList.(tmp_name).default_gui = app.InstrTable.Data{i,5};
            end 
        end
        
        % Return the name of the instrument from the selected table row
        function name = getSelectedInstrumentName(app)
            name = app.InstrTable.Data{app.row_sel,1};
        end

        function saveInstrumentList(app)
            InstrumentList = instrListFromTable(app);
            dir = getLocalBaseDir();
            file_name = fullfile(dir,'LocalInstrumentControlSettings.mat');
            save(file_name, 'InstrumentList','-append');
            setTableStatus(app, 'edits_saved');
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % The line below will throw an error if the settings file does not exist
            app.BaseDirEditField.Value = getLocalBaseDir();
            % Initialize the table
            app.InstrTable.Data = {};
            updateTable(app);
            
            % The variable, to which the output will be assigned
            app.ContinueButton.Enable = 'off';
            if isempty(varargin)
                app.Ret = MyRefVar('');
            else
                if isequal(class(varargin{1}),'MyRefVar')
                    app.Ret = varargin{1};
                    % Enable the continue button only if appropriate MyRefVar
                    % was supplied to the input
                    app.ContinueButton.Enable = 'on';
                else
                    app.ErrorwarningTextArea.Value = ...
                        ['The input variable class needs to be MyRefVar.',... 
                        ' The selected value will not be returned'];
                    app.ErrorwarningTextArea.FontColor = app.WARNING_COLOR;
                    app.Ret = MyRefVar('');
                end
            end
        end

        % Button pushed function: AddinstrumentButton
        function AddinstrumentButtonPushed(app, event)
            newaddr = MyRefVar('');
            waitfor(FindInstrumentDlg(newaddr));
            if ~isequal(newaddr.value,'')
                app.InstrTable.Data = [app.InstrTable.Data; {'placeholder',...
                    'constructor',newaddr.value,'',''}];
                setTableStatus(app, 'edits_unsaved');
            end
        end

        % Cell edit callback: InstrTable
        function InstrTableCellEdit(app, event)
            setTableStatus(app, 'edits_unsaved');
        end

        % Button pushed function: SavelistButton
        function SavelistButtonPushed(app, event)
            saveInstrumentList(app);
        end

        % Button pushed function: ReloadlistButton
        function ReloadlistButtonPushed(app, event)
            updateTable(app);
        end

        % Button pushed function: DeleteinstrumentButton
        function DeleteinstrumentButtonPushed(app, event)
            % Find the selected table row
            app.InstrTable.Data(app.row_sel, :) = [];
            setTableStatus(app, 'edits_unsaved');
        end

        % Cell selection callback: InstrTable
        function InstrTableCellSelection(app, event)
            app.row_sel = event.Indices(1,1);
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            % Save changes
            saveInstrumentList(app);
            % Find the selected table row
            name = getSelectedInstrumentName(app);
            app.Ret.value = InstrumentList.(name);
            delete(app)
        end

        % Callback function
        function CancelButtonPushed(app, event)
            % No changes saved and no value assigned in this case 
            delete(app)
        end

        % Button pushed function: GeneraterunfileButton
        function GeneraterunfileButtonPushed(app, event)
            name = getSelectedInstrumentName(app);
            InstrumentList = instrListFromTable(app);
            opts = {};
            if ~isempty(deblank(app.MenutitleEditField.Value))
                opts = [opts,{'menu_title', app.MenutitleEditField.Value}];
            end
            opts = [opts,{'show_in_daq', logical(app.ShowinDAQCheckBox.Value)}];
            generateRunFile(InstrumentList.(name), opts{:});
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ManagethelistofinstrumentsUIFigure = uifigure;
            app.ManagethelistofinstrumentsUIFigure.Name = 'Manage the list of instruments';
            app.InstrTable = uitable(app.ManagethelistofinstrumentsUIFigure);
            app.InstrTable.ColumnName = {'name'; 'interface'; 'address'; 'control class'; 'default GUI'};
            app.InstrTable.RowName = {};
            app.InstrTable.ColumnEditable = true;
            app.InstrTable.CellEditCallback = createCallbackFcn(app, @InstrTableCellEdit, true);
            app.InstrTable.CellSelectionCallback = createCallbackFcn(app, @InstrTableCellSelection, true);
            app.AddinstrumentButton = uibutton(app.ManagethelistofinstrumentsUIFigure, 'push');
            app.AddinstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @AddinstrumentButtonPushed, true);
            app.AddinstrumentButton.Text = 'Add instrument';
            app.DeleteinstrumentButton = uibutton(app.ManagethelistofinstrumentsUIFigure, 'push');
            app.DeleteinstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteinstrumentButtonPushed, true);
            app.DeleteinstrumentButton.Text = 'Delete instrument';
            app.SavelistButton = uibutton(app.ManagethelistofinstrumentsUIFigure, 'push');
            app.SavelistButton.ButtonPushedFcn = createCallbackFcn(app, @SavelistButtonPushed, true);
            app.SavelistButton.Text = 'Save list';
            app.ReloadlistButton = uibutton(app.ManagethelistofinstrumentsUIFigure, 'push');
            app.ReloadlistButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadlistButtonPushed, true);
            app.ReloadlistButton.Text = 'Reload list';
            app.ContinueButton = uibutton(app.ManagethelistofinstrumentsUIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.TabGroup = uitabgroup(app.ManagethelistofinstrumentsUIFigure);
            app.InstrumentlistTab = uitab(app.TabGroup);
            app.InstrumentlistTab.Title = 'Instrument list';
            app.StatusLabel = uilabel(app.InstrumentlistTab);
            app.StatusLabel.Text = 'Status';
            app.InstrumentListlocationLabel = uilabel(app.InstrumentlistTab);
            app.InstrumentListlocationLabel.Text = 'InstrumentList location';
            app.BaseDirEditField = uieditfield(app.InstrumentlistTab, 'text');
            app.BaseDirEditField.Editable = 'off';
            app.RunfilesTab = uitab(app.TabGroup);
            app.RunfilesTab.Title = 'Run files';
            app.GeneraterunfileButton = uibutton(app.RunfilesTab, 'push');
            app.GeneraterunfileButton.ButtonPushedFcn = createCallbackFcn(app, @GeneraterunfileButtonPushed, true);
            app.GeneraterunfileButton.Text = 'Generate run file';
            app.ShowinDAQCheckBox = uicheckbox(app.RunfilesTab);
            app.ShowinDAQCheckBox.Text = 'Show in DAQ?';
            app.ShowinDAQCheckBox.Value = true;
            app.MenutitleEditFieldLabel = uilabel(app.RunfilesTab);
            app.MenutitleEditFieldLabel.Text = 'Menu title';
            app.MenutitleEditField = uieditfield(app.RunfilesTab, 'text');
            app.OptionsLabel = uilabel(app.RunfilesTab);
            app.OptionsLabel.Text = 'Options:';
        end
    end

    methods (Access = public)

        % Construct app
        function app = InstrumentManager(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ManagethelistofinstrumentsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ManagethelistofinstrumentsUIFigure)
        end
    end
end